classdef SatelliteThermodynamicAppv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        ApplicationTitle     matlab.ui.control.Label
        ResetButton          matlab.ui.control.Button
        CpEditField          matlab.ui.control.NumericEditField
        MaterialCpLabel      matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        Help                 matlab.ui.control.Button
        SolarFlareEditField  matlab.ui.control.NumericEditField
        KpIndexLabel         matlab.ui.control.Label
        TimeminEditField     matlab.ui.control.NumericEditField
        TimeLabel            matlab.ui.control.Label
        MasskgEditField      matlab.ui.control.NumericEditField
        MassLabel            matlab.ui.control.Label
        Aream2EditField      matlab.ui.control.NumericEditField
        AreaLabel            matlab.ui.control.Label
        PowerWEditField      matlab.ui.control.NumericEditField
        PowerLabel           matlab.ui.control.Label
        StabilityLabel       matlab.ui.control.Label
        TemperatureLabel     matlab.ui.control.Label
        SimulateButton       matlab.ui.control.Button
        TempAxes             matlab.ui.control.UIAxes
        OrbitAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        
        StopSimulation = false;
    end


    methods (Access = private)
    % ODE function for temperature change
    function dTdt = temperatureODE(app, t, T, P, A, M, Cp, Kp)
        sigma = 5.67e-8; % Stefan-Boltzmann constant (W/m²·K⁴)
        s = 1361; % solar constant (W/m^2)
        alpha = 0.6; %Absorptivity of satellite surface approximation

        cycle_time = 5400; %Day night cycle, 45 minutes in seconds
        day_length = 2700; % day length in seconds
         
        if mod(t, cycle_time) < day_length
            Qin = P + (Kp * s * A * alpha); %Heat input during the day is power + (Kp *solar constant * area * alpha (absoprtivity)
        else

            Qin = P; % Heat input during night is just the power input from the payload
        end
        
        %Radioactive heat loss
        Qout = sigma * A * T^4; % Stefan-Boltzman constant * Area * Temperature ^4
        
        %Differential equation for temperature change
        dTdt = (Qin - Qout) ./ (M * Cp);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.SimulateButton.Enable = 'off'; %Disables simulate button during simulation


            % Get inputs
            P = app.PowerWEditField.Value; % power of payload
            A = app.Aream2EditField.Value; %surface area of satellite
            M = app.MasskgEditField.Value; % mass
            Cp = app.CpEditField.Value; % specific heat
            Kp = app.SolarFlareEditField.Value; % Kp index
            t_max = app.TimeminEditField.Value * 60; % convert minutes to seconds

            if P <= 0 || A <= 0 || M <= 0 || Cp <= 0 || t_max <= 0 %no negative inputs
                uialert(app.UIFigure, 'All inputs must be positive numbers.', 'Invalid Input');
                app.SimulateButton.Enable = 'on';
                    return;
            end


            %Initial temperature
            T0 = 300; % Kelvins
            tspan = 0:1:t_max; %1 second intervals creates a better plot

            %solve ODE
            [t, T] = ode15s(@(t, T) app.temperatureODE(t, T, P, A, M, Cp, Kp), tspan, T0); %Anyomous function in ode15s, ode15s can handle the data set better than ode45
            
            if T(end) > 350 % 350 Kelvin is the critical failure point
                app.StabilityLabel.Text = sprintf('Stability: Overheating %.2f K', T(end)); % alerts user of overheating
                app.StabilityLabel.FontColor = [1, 0, 0]; % Red for overheating
            else
                app.StabilityLabel.Text = sprintf('Stability: Stable %,2f K', T(end)); % informs user on label that the satellite is stable
                app.StabilityLabel.FontColor = [0, 0.5, 0]; % green for stable
            end


            %plot temperature graph
            cla(app.TempAxes); % clear app
            plot(app.TempAxes, (t / 60), T); % plot (t is put back into minutes)

            hold(app.TempAxes, 'on');
            yline(app.TempAxes, 350, '--r', 'Overheating Threshold (350K)', 'LabelHorizontalAlignment', 'left', 'LabelVerticalAlignment','bottom');

            full_cycle = 90; %LEO orbit is 90 minutes
            day = full_cycle / 2; % day has sunlight
            time_minutes = t_max / 60; % convert to minutes

            hold(app.TempAxes, 'on');
            xline(app.TempAxes, day, '--r', 'Day ends', 'LabelOrientation', 'horizontal', 'LabelVerticalAlignment', 'bottom'); % shows when day stops, and night begins (only Qin is power during night)
            xline(app.TempAxes, full_cycle, '--b', '1 Orbit', 'LabelOrientation','horizontal','LabelVerticalAlignment','bottom'); %night is over, back to day, completion of 1 cycle or orbit
            hold(app.TempAxes, 'off');
           

            xlim(app.TempAxes, [0 t_max / 60]);
            app.TempAxes.XLabel.String = 'Time (minutes)';
            app.TempAxes.YLabel.String = 'Temperature (K)';
            app.TempAxes.Title.String = sprintf('Temperature vs. Time (Cycle: %d minutes)', time_minutes);
            app.TemperatureLabel.Text = sprintf('Final Temperature: %.2f K', T(end));

    
            %%%%%%%%%% Orbit Animation 
            % Constants for orbit
            orbit_period = 90 * 60;              % 90 minutes in seconds
            omega = 2 * pi / orbit_period;       % angular velocity (rad/sec)
            inclination = deg2rad(51.6);         % inclination in radians
            a = 6771;                             % km (Earth radius + 400 km altitude)

            % Precompute orbit path for plotting (optional background path)
            theta = linspace(0, 2*pi, 360);
            x_orbit = a * cos(theta);
            y_orbit = a * sin(theta) * cos(inclination);
            z_orbit = a * sin(theta) * sin(inclination);

            % Plot orbit and Earth
            cla(app.OrbitAxes);
            plot3(app.OrbitAxes, x_orbit, y_orbit, z_orbit, 'c');  % orbit path
            hold(app.OrbitAxes, 'on');
            [xe, ye, ze] = sphere(50);
            surf(app.OrbitAxes, 6371*xe, 6371*ye, 6371*ze, ...
                'FaceColor', 'b', 'EdgeColor', 'none', 'FaceAlpha', 0.3); % Earth
            satelliteMarker = plot3(app.OrbitAxes, 0, 0, 0, 'ro', ...
                'MarkerSize', 8, 'MarkerFaceColor', 'r');
            axis(app.OrbitAxes, 'equal');
            xlabel(app.OrbitAxes, 'X (km)');
            ylabel(app.OrbitAxes, 'Y (km)');
            zlabel(app.OrbitAxes, 'Z (km)');
            title(app.OrbitAxes, '3D Satellite Orbit');
            grid(app.OrbitAxes, 'on');

            % Animate using ode15s time and temperature outputs
            app.StopSimulation = false;
            for i = 1:length(t)
            if app.StopSimulation
                disp('Simulation stopped by user.');
                    break;
            end

                    angle = omega * t(i);  % satellite’s angular position at t(i)
                    x_sat = a * cos(angle);
                    y_sat = a * sin(angle) * cos(inclination);
                    z_sat = a * sin(angle) * sin(inclination);

                    % Color changes with temperature
                    if T(i) > 350
                         color = 'r';
                    else
                         color = 'g';
            end

            % Update satellite marker position and color
            set(satelliteMarker, 'XData', x_sat, 'YData', y_sat, ...
                         'ZData', z_sat, ...
                         'MarkerFaceColor', color, ...
                         'MarkerEdgeColor', color);
                drawnow;
                pause(0.001);
             end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StopSimulation = true; %Stops animation and simulation
            pause(0.1); % Waits for it to stop preventing error


            app.SimulateButton.Enable = 'on'; % Turns simulate button back on
           

            % Reset input fields
            app.PowerWEditField.Value = 5;
            app.Aream2EditField.Value = 0.1;
            app.MasskgEditField.Value = 2;

             
            app.CpEditField.Value = 900;  % Aluminum
            app.SolarFlareEditField.Value = 1; %Default Solar Flare
            app.TimeminEditField.Value = 90;

            % Clear plots
            cla(app.TempAxes);
            cla(app.OrbitAxes);

                % Reset status labels
            app.StabilityLabel.Text = 'Stability: ---';
            app.StabilityLabel.FontColor = [0 0 0];  % black
            app.TemperatureLabel.Text = 'Final Temperature: ---';

             % Re-enable Simulate Button
            app.SimulateButton.Enable = 'on';
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
         uialert(app.UIFigure, ...
    ['If you''re unsure or something goes wrong, press **Reset** to stop the simulation and restore default values.', ...
    newline newline, ...
    'Only enter values within the allowed ranges. Hover over each input field to see helpful tooltips.'], ...
    'Help', 'Icon', 'info');   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OrbitAxes = uiaxes(app.GridLayout);
            title(app.OrbitAxes, 'Orbit Animation')
            xlabel(app.OrbitAxes, 'X')
            ylabel(app.OrbitAxes, 'Y')
            zlabel(app.OrbitAxes, 'Z')
            app.TempAxes = uiaxes(app.GridLayout);
            title(app.TempAxes, 'Temperature')
            xlabel(app.TempAxes, 'X')
            ylabel(app.TempAxes, 'Y')
            zlabel(app.TempAxes, 'Z')
            app.SimulateButton = uibutton(app.GridLayout, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TemperatureLabel = uilabel(app.GridLayout);
            app.TemperatureLabel.Text = 'Temperature:';
            app.StabilityLabel = uilabel(app.GridLayout);
            app.StabilityLabel.Text = 'Stability';
            app.PowerLabel = uilabel(app.GridLayout);
            app.PowerLabel.Text = 'Power (W):';
            app.PowerWEditField = uieditfield(app.GridLayout, 'numeric');
            app.PowerWEditField.Limits = [1 500];
            app.PowerWEditField.Tooltip = {'Payload internal power in watts (5–500 W)'};
            app.PowerWEditField.Value = 5;
            app.AreaLabel = uilabel(app.GridLayout);
            app.AreaLabel.Text = 'Area (m^2)';
            app.Aream2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Aream2EditField.Limits = [0.01 5];
            app.Aream2EditField.Tooltip = {'Exposed surface area of the satellite (0.01–5 m²)'};
            app.Aream2EditField.Value = 0.1;
            app.MassLabel = uilabel(app.GridLayout);
            app.MassLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.GridLayout, 'numeric');
            app.MasskgEditField.Limits = [1 10];
            app.MasskgEditField.Tooltip = {'Mass of the satellite (1–1000 kg)'};
            app.MasskgEditField.Value = 5;
            app.TimeLabel = uilabel(app.GridLayout);
            app.TimeLabel.Text = 'Time (min)';
            app.TimeminEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimeminEditField.Limits = [1 360];
            app.TimeminEditField.Tooltip = {'Total simulation time in minutes (1–360)'};
            app.TimeminEditField.Value = 90;
            app.KpIndexLabel = uilabel(app.GridLayout);
            app.KpIndexLabel.Text = 'Kp Index';
            app.SolarFlareEditField = uieditfield(app.GridLayout, 'numeric');
            app.SolarFlareEditField.Limits = [1 9];
            app.SolarFlareEditField.Tooltip = {'Kp index: 1 (quiet) to 9 (geomagnetic storm)'};
            app.SolarFlareEditField.Value = 1;
            app.Help = uibutton(app.GridLayout, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {' How to Use the Satellite Thermal Simulation App'; '1. Enter Input Values'; ''; '    Power (W): Internal heat generation (5–500 W)'; ''; '    Area (m²): Surface area exposed to space (0.01–5 m²)'; ''; '    Mass (kg): Total CubeSat mass (1–10 kg)'; ''; '    Cp (J/kg·K): Specific heat capacity of key material'; '    (e.g., Aluminum 7075 = 897, FR4 = 769, Battery = 800)'; ''; '    Kp Index (1–9): Solar activity level'; '    (1 = quiet, 9 = strong geomagnetic storm)'; ''; '    Time (min): Total simulation duration (1–360 min)'; '    (90 minutes = ~1 full orbit)'; ''; '2. Click “Simulate”'; ''; '    View a temperature vs. time plot'; ''; '    Watch the 3D orbital animation'; ''; '    Check thermal stability:'; ''; '        🟢 Green = Stable'; ''; '        🔴 Red = Overheating'; ''; '3. Overheating Threshold: 350 K'; ''; '    Displayed as a red dashed line on the temperature graph'; ''; '    The final temperature is shown below the plot'; ''; '4. Click “Reset”'; ''; '    Stops the simulation'; ''; '    Clears all plots and labels'; ''; '    Restores default, stable values'; ''; ' Tip: Experiment with different combinations of power, area, Cp, and Kp index to explore how orbital heating and cooling affect satellite performance.'; ''; ' Note: This simulation models a simplified CubeSat in a circular Low Earth Orbit with idealized conditions, intended for conceptual and educational purposes.'};
            app.MaterialCpLabel = uilabel(app.GridLayout);
            app.MaterialCpLabel.Text = 'Cp (J/kg*K)';
            app.CpEditField = uieditfield(app.GridLayout, 'numeric');
            app.CpEditField.Limits = [500 2000];
            app.CpEditField.Tooltip = {'Material heat capacity (500–2000 J/kg·K)'};
            app.CpEditField.Value = 900;
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ApplicationTitle = uilabel(app.GridLayout);
            app.ApplicationTitle.Text = '      Satellite Thermal Simulation in Low Earth Orbit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatelliteThermodynamicAppv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end