classdef SatelliteThermodynamicAppv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StabilityLabel           matlab.ui.control.Label
        SolarFlareEditField      matlab.ui.control.NumericEditField
        KpIndexLabel             matlab.ui.control.Label
        TemperatureLabel         matlab.ui.control.Label
        TimeminEditField         matlab.ui.control.NumericEditField
        TimeLabel                matlab.ui.control.Label
        CpspecificheatEditField  matlab.ui.control.NumericEditField
        CpLabel                  matlab.ui.control.Label
        MasskgEditField          matlab.ui.control.NumericEditField
        MassLabel                matlab.ui.control.Label
        Aream2EditField          matlab.ui.control.NumericEditField
        AreaLabel                matlab.ui.control.Label
        PowerWEditField          matlab.ui.control.NumericEditField
        PowerLabel               matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        SimulateButton           matlab.ui.control.Button
        OrbitAxes                matlab.ui.control.UIAxes
        TempAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        
        StopSimulation = false;
    end


    methods (Access = private)
    % ODE function for temperature change
    function dTdt = temperatureODE(app, t, T, P, A, M, Cp, Kp)
        sigma = 5.67e-8; % Stefan-Boltzmann constant (W/m²·K⁴)
        s = 1361; % solar constant (W/m^2)
        alpha = 0.6; %Absorptivity of satellite surface approximation

        cycle_time = 5400; %Day night cycle, 45 minutes in seconds
        day_length = 2700; % day length in seconds
         
        if mod(t, cycle_time) < day_length
            Qin = P + (Kp * s * A * alpha); %Heat input during the day is power + (Kp *solar constant * area * alpha (absoprtivity)
        else

            Qin = P; % Heat input during night is just the power input from the payload
        end
        
        %Radioactive heat loss
        Qout = sigma * A * T^4; % Stefan-Boltzman constant * Area * Temperature ^4
        
        %Differential equation for temperature change
        dTdt = (Qin - Qout) / (M * Cp);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            %clear previous plot
            cla(app.TempAxes);


            % Get inputs
            P = app.PowerWEditField.Value; % power of payload
            A = app.Aream2EditField.Value; %surface area of satellite
            M = app.MasskgEditField.Value; % mass
            Cp = app.CpspecificheatEditField.Value; % specific heat
            Kp = app.SolarFlareEditField.Value; % Kp index
            t_max = app.TimeminEditField.Value * 60; % convert minutes to seconds


            %Initial temperature
            T0 = 300; % Kelvins
            tspan = 0:1:t_max; %1 second intervals creates a better plot

            %solve ODE
            [t, T] = ode15s(@(t, T) app.temperatureODE(t, T, P, A, M, Cp, Kp), tspan, T0); %Anyomous function in ode15s, ode15s can handle the data set better than ode45
            
            if T(end) > 350 % 350 Kelvin is the critical failure point
                app.StabilityLabel.Text = sprintf('Stability: Overheating %.2f K', T(end)); % alerts user of overheating
                app.StabilityLabel.FontColor = [1, 0, 0]; % Red for overheating
            else
                app.StabilityLabel.Text = sprintf('Stability: Stable %,2f K', T(end)); % informs user on label that the satellite is stable
                app.StabilityLabel.FontColor = [0, 0.5, 0]; % green for stable
            end


            %plot temperature graph
            cla(app.TempAxes); % clear app
            plot(app.TempAxes, (t / 60), T); % plot (t is put back into minutes)

            full_cycle = 90; %LEO orbit is 90 minutes
            day = full_cycle / 2; % day has sunlight
            time_minutes = t_max / 60; % convert to minutes

            hold(app.TempAxes, 'on');
            xline(app.TempAxes, day, '--r', 'Day-Night Transition', 'LabelOrientation', 'horizontal', 'LabelVerticalAlignment', 'bottom'); % shows when day stops, and night begins (only Qin is power during night)
            xline(app.TempAxes, full_cycle, '--b', 'Cycle End', 'LabelOrientation','horizontal','LabelVerticalAlignment','bottom'); %night is over, back to day, completion of 1 cycle or orbit
            hold(app.TempAxes, 'off');
           

            xlim(app.TempAxes, [0 t_max / 60]);
            app.TempAxes.XLabel.String = 'Time (minutes)';
            app.TempAxes.YLabel.String = 'Temperature (K)';
            app.TempAxes.Title.String = sprintf('Temperature vs. Time (Cycle: %d minutes)', time_minutes);
            app.TemperatureLabel.Text = sprintf('Final Temperature: %.2f K', T(end));

            %plot for orbit
            theta = linspace(0, 2 * pi, 100); % angle array around earth - 2pi is a circle (orbit)
            x_orbit = cos(theta); % x values are cos(theta)
            y_orbit = sin(theta); % y values are sin(theta)

            cla(app.OrbitAxes); % clear graph
            plot(app.OrbitAxes, x_orbit, y_orbit, 'b'); % plot orbit path

            hold(app.OrbitAxes, 'on');

            earthMarker = plot(app.OrbitAxes, 0, 0, 'bo', 'MarkerSize', 12, 'MarkerFaceColor', 'b'); %plot earth
            satelliteMarker = plot(app.OrbitAxes, 0, 0, 'go', 'MarkerSize', 8, 'MarkerFaceColor', 'g'); % plot satellite
            hold(app.OrbitAxes, 'off');

                
            app.StopSimulation = false;
            for j = 1:length(t)
                if app.StopSimulation == true
                  
                    disp('Simulation stopped by user.');
                    break;
                end
            for i = 1:length(t)
              

                if app.StopSimulation == true
                    break;
                end
                
                angle = 2 * pi * mod(t(i), full_cycle) / full_cycle;
                x_sat = cos(angle);
                y_sat = sin(angle);

                if T(i) > 350
                    set(satelliteMarker, 'XData', x_sat, 'YData', y_sat, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'r'); % Satellite is marked as red as it orbits, as a warning for overheating
                else
                    set(satelliteMarker, 'XData', x_sat, 'YData', y_sat, 'MarkerFaceColor', 'g', 'MarkerEdgeColor', 'g'); % satellite is marked green to show it is stables as it orbits

                end
                drawnow;
                pause(0.001);
            end

              if app.StopSimulation == true
                    break;
                end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopSimulation = true;
 
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TempAxes = uiaxes(app.UIFigure);
            title(app.TempAxes, 'Temperature')
            xlabel(app.TempAxes, 'X')
            ylabel(app.TempAxes, 'Y')
            zlabel(app.TempAxes, 'Z')
            app.OrbitAxes = uiaxes(app.UIFigure);
            title(app.OrbitAxes, 'Orbit Animation')
            xlabel(app.OrbitAxes, 'X')
            ylabel(app.OrbitAxes, 'Y')
            zlabel(app.OrbitAxes, 'Z')
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.PowerLabel = uilabel(app.UIFigure);
            app.PowerLabel.Text = 'Power (W):';
            app.PowerWEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerWEditField.Value = 50;
            app.AreaLabel = uilabel(app.UIFigure);
            app.AreaLabel.Text = 'Area (m^2)';
            app.Aream2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Aream2EditField.Limits = [0.1 10];
            app.Aream2EditField.Value = 0.1;
            app.MassLabel = uilabel(app.UIFigure);
            app.MassLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.Value = 1.33;
            app.CpLabel = uilabel(app.UIFigure);
            app.CpLabel.Text = 'Cp (specific heat)';
            app.CpspecificheatEditField = uieditfield(app.UIFigure, 'numeric');
            app.CpspecificheatEditField.Value = 900;
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time (min)';
            app.TimeminEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeminEditField.Value = 90;
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature:';
            app.KpIndexLabel = uilabel(app.UIFigure);
            app.KpIndexLabel.Text = 'Kp Index';
            app.SolarFlareEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolarFlareEditField.Limits = [1 9];
            app.SolarFlareEditField.Value = 1;
            app.StabilityLabel = uilabel(app.UIFigure);
            app.StabilityLabel.Text = 'Stability';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatelliteThermodynamicAppv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end